classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RunButton                      matlab.ui.control.Button
        EdgedetectionoperatorDropDown  matlab.ui.control.DropDown
        EdgedetectionoperatorDropDownLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ObjectSegmentationTab          matlab.ui.container.Tab
        CustomthresholdEditField       matlab.ui.control.NumericEditField
        CustomthresholdEditFieldLabel  matlab.ui.control.Label
        ThresholdDropDown              matlab.ui.control.DropDown
        ThresholdDropDownLabel         matlab.ui.control.Label
        LineDetectionTab               matlab.ui.container.Tab
        VotethresholdEditField         matlab.ui.control.NumericEditField
        VotethresholdEditFieldLabel    matlab.ui.control.Label
        FilenameLabel                  matlab.ui.control.Label
        ChoosefileButton               matlab.ui.control.Button
        ObjectSegmentationLineDetectionLabel  matlab.ui.control.Label
        OutputImage                    matlab.ui.control.UIAxes
        EdgeImage                      matlab.ui.control.UIAxes
        InputImage                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            [filename, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputImage);
                img = imread(fullfile(path, filename));
                app.FilenameLabel.Text = filename;
        
                imshow(img, 'Parent', app.InputImage);
                app.ImageInput = img;

                %cla(app.EdgeImage);
                %cla(app.OutputImage);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiaxes(app.UIFigure);
            title(app.InputImage, 'Input Image')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.EdgeImage = uiaxes(app.UIFigure);
            title(app.EdgeImage, 'Edge Image')
            app.EdgeImage.XTick = [];
            app.EdgeImage.YTick = [];
            app.OutputImage = uiaxes(app.UIFigure);
            title(app.OutputImage, 'Output Image')
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.ObjectSegmentationLineDetectionLabel = uilabel(app.UIFigure);
            app.ObjectSegmentationLineDetectionLabel.Text = {'Object Segmentation & '; 'Line Detection'};
            app.ChoosefileButton = uibutton(app.UIFigure, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'File name';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObjectSegmentationTab = uitab(app.TabGroup);
            app.ObjectSegmentationTab.Title = 'Object Segmentation';
            app.ThresholdDropDownLabel = uilabel(app.ObjectSegmentationTab);
            app.ThresholdDropDownLabel.Text = 'Threshold';
            app.ThresholdDropDown = uidropdown(app.ObjectSegmentationTab);
            app.ThresholdDropDown.Items = {'Adaptive', 'Global', 'Otsu', 'Input'};
            app.ThresholdDropDown.Value = 'Adaptive';
            app.CustomthresholdEditFieldLabel = uilabel(app.ObjectSegmentationTab);
            app.CustomthresholdEditFieldLabel.Text = 'Custom threshold';
            app.CustomthresholdEditField = uieditfield(app.ObjectSegmentationTab, 'numeric');
            app.CustomthresholdEditField.Limits = [0 1];
            app.CustomthresholdEditField.Value = 0.1;
            app.LineDetectionTab = uitab(app.TabGroup);
            app.LineDetectionTab.Title = 'Line Detection';
            app.VotethresholdEditFieldLabel = uilabel(app.LineDetectionTab);
            app.VotethresholdEditFieldLabel.Text = 'Vote threshold';
            app.VotethresholdEditField = uieditfield(app.LineDetectionTab, 'numeric');
            app.VotethresholdEditField.Limits = [0 Inf];
            app.VotethresholdEditField.RoundFractionalValues = 'on';
            app.VotethresholdEditField.Value = 100;
            app.EdgedetectionoperatorDropDownLabel = uilabel(app.UIFigure);
            app.EdgedetectionoperatorDropDownLabel.Text = 'Edge detection operator';
            app.EdgedetectionoperatorDropDown = uidropdown(app.UIFigure);
            app.EdgedetectionoperatorDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgedetectionoperatorDropDown.Placeholder = 'Choose operator';
            app.EdgedetectionoperatorDropDown.Value = 'Laplace';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end